classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel  matlab.ui.container.Panel
        STARTButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        MENUPanel                      matlab.ui.container.Panel
        ClasificarButton               matlab.ui.control.Button
        EntrenarButton                 matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        ClassificationPanel            matlab.ui.container.Panel
        LoadcustomimageButton          matlab.ui.control.Button
        DownloadImageButton            matlab.ui.control.Button
        HumanThresholdSlider           matlab.ui.control.RangeSlider
        HumanThresholdSliderLabel      matlab.ui.control.Label
        GeneratingboundingboxesLabel   matlab.ui.control.Label
        FindingpeopleinimageLabel      matlab.ui.control.Label
        ReadingimageLabel              matlab.ui.control.Label
        LoadingdetectorLabel           matlab.ui.control.Label
        BoundingBoxcolorDropDown       matlab.ui.control.DropDown
        BoundingBoxcolorDropDownLabel  matlab.ui.control.Label
        DetectButton                   matlab.ui.control.Button
        Image_to_detect                matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtomPushed(app, event)
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel.Visible = 'off';
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel.Visible = "off";
            app.MENUPanel.Visible = 'on';
        end

        % Callback function
        function CLASSIFICATION_Buttom_Pushed(app, event)
            app.MENUPanel.Visible = "off";
            app.ClassificationPanel.Visible = "on";
        end

        % Button pushed function: DetectButton
        function DetectButton_Pushed(app, event)

            try
                    %Cargar el detector
                    app.LoadingdetectorLabel.Visible = "on";
                    drawnow;
                    loadedData = load('trainedDetector_yolox_10e.mat');
                    detector = loadedData.detector;
        
                    
                    % Leer la imagen para detección
                    app.LoadingdetectorLabel.Visible = "off";
                    app.ReadingimageLabel.Visible="on";
                    drawnow;
                    imageToDetect = imread(app.Image_to_detect.ImageSource);  % Cambia a la imagen que prefieras
                    
                    % Realizar la detección en la imagen
                    app.ReadingimageLabel.Visible="off";
                    app.FindingpeopleinimageLabel.Visible = "on";
                    drawnow;
                    human_threshold = app.HumanThresholdSlider.Value;
                    [bboxes, scores, labels] = detect(detector, imageToDetect, 'Threshold', human_threshold(1), 'AutoResize', false);
                    

                    % Anotar la imagen con los resultados de detección
                    app.FindingpeopleinimageLabel.Visible = "off";
                    app.GeneratingboundingboxesLabel.Visible = "on";
                    drawnow;
                    color = app.BoundingBoxcolorDropDown.Value;
                    annotatedImage = insertObjectAnnotation(imageToDetect, "rectangle", bboxes, scores, 'Color', color);
                

                    % Actualizar la imagen en el componente Image
                    app.Image_to_detect.ImageSource = annotatedImage;
                    app.GeneratingboundingboxesLabel.Visible = "off";
                    app.DownloadImageButton.Visible = "on";
                catch ME
                    % Mostrar un mensaje de error detallado en caso de fallo
                    disp('Error durante la detección:');
                    disp(ME.message);
                end
        end

        % Button pushed function: ClasificarButton
        function Classify_Button_pushed(app, event)
            app.MENUPanel.Visible = "off";
            app.MENUPanel.Visible = "off";
            app.ClassificationPanel.Visible = "on";
        end

        % Value changed function: BoundingBoxcolorDropDown
        function BB_color_changed(app, event)
            value = app.BoundingBoxcolorDropDown.Value;
            
        end

        % Button pushed function: DownloadImageButton
        function Download_button_pushed(app, event)
            full_path = fullfile(pwd, 'Apps/detected_image.png');
            imwrite(app.Image_to_detect.ImageSource, full_path)
        end

        % Button pushed function: LoadcustomimageButton
        function Load_custom_image_pushed(app, event)
           % Definir los filtros de archivo
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            

            [file, path] = uigetfile(filterspec);

            fullFilePath = fullfile(path, file);
        
            % Actualizar la imagen
            app.Image_to_detect.ImageSource = fullFilePath;
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ClassificationPanel = uipanel(app.UIFigure);
            app.ClassificationPanel.Title = 'Classification';
            app.ClassificationPanel.Visible = 'off';
            app.Image3 = uiimage(app.ClassificationPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'smooth-elegant-transparent-blue-cloth.jpg');
            app.Image_to_detect = uiimage(app.ClassificationPanel);
            app.Image_to_detect.ImageSource = fullfile(pathToMLAPP, '15.jpg');
            app.DetectButton = uibutton(app.ClassificationPanel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButton_Pushed, true);
            app.DetectButton.Icon = fullfile(pathToMLAPP, 'images', 'pngtree-vector-star-icon-png-image_5169247.jpg');
            app.DetectButton.Text = 'Detect';
            app.BoundingBoxcolorDropDownLabel = uilabel(app.ClassificationPanel);
            app.BoundingBoxcolorDropDownLabel.Text = 'Bounding Box color';
            app.BoundingBoxcolorDropDown = uidropdown(app.ClassificationPanel);
            app.BoundingBoxcolorDropDown.Items = {'blue', 'green', 'red', 'cyan', 'magenta', 'yellow', 'black', 'white', 'b', 'k'};
            app.BoundingBoxcolorDropDown.ValueChangedFcn = createCallbackFcn(app, @BB_color_changed, true);
            app.BoundingBoxcolorDropDown.Value = 'yellow';
            app.LoadingdetectorLabel = uilabel(app.ClassificationPanel);
            app.LoadingdetectorLabel.Visible = 'off';
            app.LoadingdetectorLabel.Text = 'Loading detector...';
            app.ReadingimageLabel = uilabel(app.ClassificationPanel);
            app.ReadingimageLabel.Visible = 'off';
            app.ReadingimageLabel.Text = 'Reading image...';
            app.FindingpeopleinimageLabel = uilabel(app.ClassificationPanel);
            app.FindingpeopleinimageLabel.Visible = 'off';
            app.FindingpeopleinimageLabel.Text = 'Finding people in image...';
            app.GeneratingboundingboxesLabel = uilabel(app.ClassificationPanel);
            app.GeneratingboundingboxesLabel.Visible = 'off';
            app.GeneratingboundingboxesLabel.Text = 'Generating bounding boxes...';
            app.HumanThresholdSliderLabel = uilabel(app.ClassificationPanel);
            app.HumanThresholdSliderLabel.Text = 'Human Threshold';
            app.HumanThresholdSlider = uislider(app.ClassificationPanel, 'range');
            app.HumanThresholdSlider.Limits = [0 1];
            app.HumanThresholdSlider.Value = [0 1];
            app.DownloadImageButton = uibutton(app.ClassificationPanel, 'push');
            app.DownloadImageButton.ButtonPushedFcn = createCallbackFcn(app, @Download_button_pushed, true);
            app.DownloadImageButton.Visible = 'off';
            app.DownloadImageButton.Text = 'Download Image';
            app.LoadcustomimageButton = uibutton(app.ClassificationPanel, 'push');
            app.LoadcustomimageButton.ButtonPushedFcn = createCallbackFcn(app, @Load_custom_image_pushed, true);
            app.LoadcustomimageButton.Text = 'Load custom image';
            app.MENUPanel = uipanel(app.UIFigure);
            app.MENUPanel.Title = 'MENU';
            app.MENUPanel.Visible = 'off';
            app.Image2 = uiimage(app.MENUPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', '1870218e6a5192b81d5e4078405537e6.jpg');
            app.EntrenarButton = uibutton(app.MENUPanel, 'push');
            app.EntrenarButton.Text = 'Entrenar';
            app.ClasificarButton = uibutton(app.MENUPanel, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @Classify_Button_pushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel = uipanel(app.UIFigure);
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel.Title = 'RECONOCIMIENTO DE PERSONAS EN ENTORNOS NATURALES';
            app.Image = uiimage(app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'main_panel.jpg');
            app.STARTButton = uibutton(app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtomPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end